classdef HARAM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HARAMUIFigure            matlab.ui.Figure
        OFANIDEALAFTERBURNINGTURBOJETLabel  matlab.ui.control.Label
        RunAnalysisButton        matlab.ui.control.Button
        UserGuideButton          matlab.ui.control.Button
        PARAMETRICCYCLEANALYSISPCALabel  matlab.ui.control.Label
        TabGroup                 matlab.ui.container.TabGroup
        FmvscTab                 matlab.ui.container.Tab
        ThrustAxes               matlab.ui.control.UIAxes
        SvscTab                  matlab.ui.container.Tab
        SFCAxes                  matlab.ui.control.UIAxes
        fvscTab                  matlab.ui.container.Tab
        FuelRatioAxes1           matlab.ui.control.UIAxes
        fvsMTab                  matlab.ui.container.Tab
        FuelRatioAxes2           matlab.ui.control.UIAxes
        n_TTab                   matlab.ui.container.Tab
        n_TAxes                  matlab.ui.control.UIAxes
        n_PTab                   matlab.ui.container.Tab
        n_PAxes                  matlab.ui.control.UIAxes
        n_OTab                   matlab.ui.container.Tab
        n_OAxes                  matlab.ui.control.UIAxes
        StatusTextArea           matlab.ui.control.TextArea
        StatusTextAreaLabel      matlab.ui.control.Label
        EngineParametersPanel    matlab.ui.container.Panel
        PiCEditField             matlab.ui.control.EditField
        cRangeEditFieldLabel     matlab.ui.control.Label
        Tt7EditField             matlab.ui.control.NumericEditField
        Tt7KLabel                matlab.ui.control.Label
        Tt4EditField             matlab.ui.control.NumericEditField
        Tt4KLabel                matlab.ui.control.Label
        FlightConditionsPanel    matlab.ui.container.Panel
        MachRangeEditField       matlab.ui.control.EditField
        MachRangeEditFieldLabel  matlab.ui.control.Label
        AltitudeEditField        matlab.ui.control.NumericEditField
        AltitudemLabel           matlab.ui.control.Label
    end

    
     methods (Access = private)
        
        function startupFcn(app)
    app.StatusTextArea.Value = {'Ready for analysis.'};
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
            app.StatusTextArea.Value = {'Running analysis...'};
drawnow;
            runAnalysis(app);
        end
        
       function runAnalysis(app)
    % Constants
    gamma = 1.4;
    cp = 1004.83;        % J/kg.K
    hPR = 42798000;      % J/kg
    R = cp * (1 - 1/gamma);

    % Get Inputs
    altitude = app.AltitudeEditField.Value;
    Tt4 = app.Tt4EditField.Value;
    Tt7 = app.Tt7EditField.Value;

    try
        Mvec = str2num(char(app.MachRangeEditField.Value));
        pic_range = str2num(char(app.PiCEditField.Value));
    catch
        uialert(app.HARAMUIFigure, 'Check Mach and πc ranges. Format: 0:0.5:2', 'Input Error');
        return;
    end

    % Input validation
    if isempty(Mvec) || any(Mvec < 0) || isempty(pic_range) || any(pic_range < 1)
        uialert(app.HARAMUIFigure, 'Check ranges: Mach ≥ 0, πc ≥ 1', 'Input Error');
        return;
    end
    if Tt4 <= 0 || Tt7 <= 0
        uialert(app.HARAMUIFigure, 'Tt4 and Tt7 must be > 0', 'Input Error');
        return;
    end

    % --- ATM.xlsx excel ---
    atm_data = readtable('atmosphere.xlsx');

    h_vec = atm_data.h;
    T_vec = atm_data.T;
    P_vec = atm_data.P;

    [h_vec, ia, ~] = unique(h_vec, 'stable');
    T_vec = T_vec(ia);
    P_vec = P_vec(ia);

    theta_vec = T_vec / 288.15;
    delta_vec = P_vec / 101325;

    % Interpolation
    theta = interp1(h_vec, theta_vec, altitude, 'linear', 'extrap');
    delta = interp1(h_vec, delta_vec, altitude, 'linear', 'extrap');

    % Atm conditions
    T0 = theta * 288.15;             % [K]
    P0 = delta * 101325;             % [Pa]
    a0 = sqrt(gamma * R * T0);       % [m/s]

    % Preallocate
    [X, ~] = meshgrid(pic_range, Mvec);
    Fm0 = zeros(size(X));
    S = zeros(size(X));
    f_total = zeros(size(X));
    f1 = zeros(size(X));
    etaP = zeros(size(X));
    etaT = zeros(size(X));
    etaO = zeros(size(X));

    for i = 1:numel(Mvec)
        M0 = Mvec(i);

        % Calculate ratios
        tau_r = 1 + (gamma - 1)/2 * M0^2;
        tau_lambda = Tt4 / T0;
        tau_lambdaAB = Tt7 / T0;

        for j = 1:numel(pic_range)
            piC = pic_range(j);
            tau_c = piC^((gamma-1)/gamma);

            % Exit velocity ratio (V9/a0)^2
            numerator = tau_lambda / (tau_r * tau_c);
            denominator = tau_lambda - tau_r * (tau_c - 1);
            V9_a0_sq = (2 / (gamma - 1)) * tau_lambdaAB * (1 - numerator / denominator);


            if V9_a0_sq < 0
                V9_a0_sq = 0; % physically meaningless, clip at 0
            end

            V9_a0 = sqrt(V9_a0_sq);

            % Specific thrust
            Fm0(i,j) = a0 * (V9_a0 - M0);

            % Fuel-to-air ratio
            f_total(i,j) = (cp * T0 / hPR) * (tau_lambdaAB - tau_r);
            
             % f1 
             f1(i,j) = (cp * T0 / hPR) * (tau_lambda - tau_r * tau_c); 
                  

            % Specific fuel consumption
 
            S(i,j) = (f_total(i,j) / Fm0(i,j)) * 10^6;  % [s/m]


            % Propulsive efficiency
            etaP(i,j) = (2 * M0) / (V9_a0 + M0);

            % Thermal efficiency
            etaT(i,j) = ((gamma-1) * cp * T0 * (V9_a0_sq - M0^2)) / (2 * f_total(i,j) * hPR);

            % Overall efficiency
            etaO(i,j) = etaP(i,j) .* etaT(i,j);
        end
    end

    % --- Plot 1: F/m0 vs πc ---
    cla(app.ThrustAxes);
    hold(app.ThrustAxes, 'on');
    plot(app.ThrustAxes, pic_range, Fm0', 'LineWidth', 1.5);
    title(app.ThrustAxes, 'F/\itṁ₀ vs \pi_c');
    xlabel(app.ThrustAxes, '\pi_c');
    ylabel(app.ThrustAxes, 'F/\itṁ₀ (m/s)');
    legend(app.ThrustAxes, arrayfun(@(x) sprintf('M=%.1f',x), Mvec, 'UniformOutput', false));
    grid(app.ThrustAxes, 'on');

    % --- Plot 2: SFC vs πc ---
    cla(app.SFCAxes);
    hold(app.SFCAxes, 'on');
    plot(app.SFCAxes, pic_range, S', 'LineWidth', 1.5);
    title(app.SFCAxes, 'TSFC vs \pi_c');
    xlabel(app.SFCAxes, '\pi_c');
    ylabel(app.SFCAxes, 'TSFC [s/m*(10^-6)]');
    legend(app.SFCAxes, arrayfun(@(x) sprintf('M=%.1f',x), Mvec, 'UniformOutput', false));
    grid(app.SFCAxes, 'on');

    % --- Plot 3: f1 vs πc ---
    cla(app.FuelRatioAxes1);
    hold(app.FuelRatioAxes1, 'on');
    plot(app.FuelRatioAxes1, pic_range, f1', 'LineWidth', 1.5);
    title(app.FuelRatioAxes1, 'f vs \pi_c');
    xlabel(app.FuelRatioAxes1, '\pi_c');
    ylabel(app.FuelRatioAxes1, 'Fuel-to-Air Ratio f');
    legend(app.FuelRatioAxes1, arrayfun(@(x) sprintf('M=%.1f',x), Mvec, 'UniformOutput', false));
    grid(app.FuelRatioAxes1, 'on');

    % --- Plot 4: f1 vs M0 for selected πc ---
    cla(app.FuelRatioAxes2);
    hold(app.FuelRatioAxes2, 'on');
    
    for j = 1:numel(pic_range)
       plot(app.FuelRatioAxes2, Mvec, f1(:,j), ...
        'LineWidth', 1.5, ...
        'DisplayName', sprintf('\\pi_c=%.2f', pic_range(j)));
    end

    title(app.FuelRatioAxes2, 'f vs M_0');
    xlabel(app.FuelRatioAxes2, 'M_0');
    ylabel(app.FuelRatioAxes2, 'Fuel-to-Air Ratio f');
    legend(app.FuelRatioAxes2, 'show');
    grid(app.FuelRatioAxes2, 'on');
    % Efficiency percent
etaT = etaT * 100;
etaP = etaP * 100;
etaO = etaO * 100;

% --- Plot 5: η_T for all Mach ---
cla(app.n_TAxes);
hold(app.n_TAxes, 'on');
for i = 1:numel(Mvec)
    plot(app.n_TAxes, pic_range, etaT(i,:), 'LineWidth', 1.5, 'DisplayName', sprintf('M = %.1f', Mvec(i)));
end
title(app.n_TAxes, '\eta_T vs \pi_c');
xlabel(app.n_TAxes, '\pi_c');
ylabel(app.n_TAxes, '\eta_T (%)');
legend(app.n_TAxes, 'show');
grid(app.n_TAxes, 'on');

% --- Plot 6: η_P for all Mach ---
cla(app.n_PAxes);
hold(app.n_PAxes, 'on');
for i = 1:numel(Mvec)
    plot(app.n_PAxes, pic_range, etaP(i,:), 'LineWidth', 1.5, 'DisplayName', sprintf('M = %.1f', Mvec(i)));
end
title(app.n_PAxes, '\eta_P vs \pi_c');
xlabel(app.n_PAxes, '\pi_c');
ylabel(app.n_PAxes, '\eta_P (%)');
legend(app.n_PAxes, 'show');
grid(app.n_PAxes, 'on');

% --- Plot 7: η_O for all Mach ---
cla(app.n_OAxes);
hold(app.n_OAxes, 'on');
for i = 1:numel(Mvec)
    plot(app.n_OAxes, pic_range, etaO(i,:), 'LineWidth', 1.5, 'DisplayName', sprintf('M = %.1f', Mvec(i)));
end
title(app.n_OAxes, '\eta_O vs \pi_c');
xlabel(app.n_OAxes, '\pi_c');
ylabel(app.n_OAxes, '\eta_O (%)');
legend(app.n_OAxes, 'show');
grid(app.n_OAxes, 'on');

app.StatusTextArea.Value = {
    'Analysis completed successfully.'
    ['Altitude = ' num2str(altitude, '%.2f') ' m']
    ['T0 = ' num2str(T0, '%.2f') ' K']
    ['P0 = ' num2str(P0, '%.2f') ' Pa']
    ['a0 = ' num2str(a0, '%.2f') ' m/s']
    ['Mach points = ' num2str(length(Mvec))]
    ['πc points = ' num2str(length(pic_range))]
};
        end

        % Button pushed function: UserGuideButton
        function UserGuideButtonPushed(app, event)
            fig = uifigure('Name', 'User Guide', 'Position', [300 200 600 400]);

    txt = uitextarea(fig, ...
        'Position', [20 20 560 360], ...
        'Editable', 'off', ...
        'FontSize', 16);
    txt.Value = {
        '======================= USER GUIDE ======================='
        ' '
        'WELCOME TO HARAM'
        ' '
        'This program has been developed to perform the parametric cycle analysis of an ideal afterburning turbojet engine under user-defined operating conditions. '
        ' '
        'To run the application, please follow the steps below and make sure that all inputs are entered in SI units. '
        ' '
        '1. Enter altitude in meters. (i.e., 3048)'
        '2. Enter Mach range in startpoint:stepsize:endpoint format. (i.e., 0.5:0.5:2)'
        '3. Enter Tt4 value. (i.e., 1400)'
        '4. Enter Tt7 value. (i.e., 2000)'
        '5. Enter  πc range in startpoint:stepsize:endpoint format. (i.e., 8:4:24)'
        '6. Press Run Analysis.'
        '7. View plots in the tabs.'
        
    };
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.HARAMUIFigure = uifigure('Visible', 'off');
            app.HARAMUIFigure.Name = 'HARAM';
            app.HARAMUIFigure.Icon = fullfile(pathToMLAPP, 'logo.jpg');
            app.FlightConditionsPanel = uipanel(app.HARAMUIFigure);
            app.FlightConditionsPanel.Title = 'Flight Conditions';
            app.AltitudemLabel = uilabel(app.FlightConditionsPanel);
            app.AltitudemLabel.Text = 'Altitude [m]';
            app.AltitudeEditField = uieditfield(app.FlightConditionsPanel, 'numeric');
            app.AltitudeEditField.Value = 3048;
            app.MachRangeEditFieldLabel = uilabel(app.FlightConditionsPanel);
            app.MachRangeEditFieldLabel.Text = 'Mach Range ';
            app.MachRangeEditField = uieditfield(app.FlightConditionsPanel, 'text');
            app.MachRangeEditField.Value = '0.5:0.5:2';
            app.EngineParametersPanel = uipanel(app.HARAMUIFigure);
            app.EngineParametersPanel.Title = 'Engine Parameters';
            app.Tt4KLabel = uilabel(app.EngineParametersPanel);
            app.Tt4KLabel.Text = 'Tt4 [K]';
            app.Tt4EditField = uieditfield(app.EngineParametersPanel, 'numeric');
            app.Tt4EditField.Value = 1400;
            app.Tt7KLabel = uilabel(app.EngineParametersPanel);
            app.Tt7KLabel.Text = 'Tt7 [K]';
            app.Tt7EditField = uieditfield(app.EngineParametersPanel, 'numeric');
            app.Tt7EditField.Value = 2000;
            app.cRangeEditFieldLabel = uilabel(app.EngineParametersPanel);
            app.cRangeEditFieldLabel.Text = 'πc Range:';
            app.PiCEditField = uieditfield(app.EngineParametersPanel, 'text');
            app.PiCEditField.Value = '8:4:24';
            app.StatusTextAreaLabel = uilabel(app.HARAMUIFigure);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.HARAMUIFigure);
            app.StatusTextArea.Value = {'Ready for analysis.'; ''};
            app.TabGroup = uitabgroup(app.HARAMUIFigure);
            app.FmvscTab = uitab(app.TabGroup);
            app.FmvscTab.Title = 'F/ṁ₀ vs πc';
            app.ThrustAxes = uiaxes(app.FmvscTab);
            title(app.ThrustAxes, 'F/ṁ₀ vs πc')
            xlabel(app.ThrustAxes, ' πc')
            ylabel(app.ThrustAxes, 'F/ṁ₀')
            zlabel(app.ThrustAxes, 'Z')
            app.SvscTab = uitab(app.TabGroup);
            app.SvscTab.Title = 'S vs πc';
            app.SFCAxes = uiaxes(app.SvscTab);
            title(app.SFCAxes, 'S vs πc')
            xlabel(app.SFCAxes, ' πc')
            ylabel(app.SFCAxes, 'S')
            zlabel(app.SFCAxes, 'Z')
            app.fvscTab = uitab(app.TabGroup);
            app.fvscTab.Title = 'f vs πc';
            app.FuelRatioAxes1 = uiaxes(app.fvscTab);
            title(app.FuelRatioAxes1, 'f vs πc')
            xlabel(app.FuelRatioAxes1, ' πc')
            ylabel(app.FuelRatioAxes1, 'f')
            zlabel(app.FuelRatioAxes1, 'Z')
            app.fvsMTab = uitab(app.TabGroup);
            app.fvsMTab.Title = 'f vs M₀';
            app.FuelRatioAxes2 = uiaxes(app.fvsMTab);
            title(app.FuelRatioAxes2, 'f vs M₀')
            xlabel(app.FuelRatioAxes2, 'M₀')
            ylabel(app.FuelRatioAxes2, 'f ')
            zlabel(app.FuelRatioAxes2, 'Z')
            app.n_TTab = uitab(app.TabGroup);
            app.n_TTab.Title = 'n_T';
            app.n_TAxes = uiaxes(app.n_TTab);
            title(app.n_TAxes, 'n_T')
            xlabel(app.n_TAxes, ' πc')
            ylabel(app.n_TAxes, 'n_T')
            zlabel(app.n_TAxes, 'Z')
            app.n_PTab = uitab(app.TabGroup);
            app.n_PTab.Title = 'n_P';
            app.n_PAxes = uiaxes(app.n_PTab);
            title(app.n_PAxes, 'n_P')
            xlabel(app.n_PAxes, ' πc')
            ylabel(app.n_PAxes, 'n_P')
            zlabel(app.n_PAxes, 'Z')
            app.n_OTab = uitab(app.TabGroup);
            app.n_OTab.Title = 'n_O';
            app.n_OAxes = uiaxes(app.n_OTab);
            title(app.n_OAxes, 'n_O')
            xlabel(app.n_OAxes, ' πc')
            ylabel(app.n_OAxes, 'n_O')
            zlabel(app.n_OAxes, 'Z')
            app.PARAMETRICCYCLEANALYSISPCALabel = uilabel(app.HARAMUIFigure);
            app.PARAMETRICCYCLEANALYSISPCALabel.Text = 'PARAMETRIC CYCLE ANALYSIS (PCA) ';
            app.UserGuideButton = uibutton(app.HARAMUIFigure, 'push');
            app.UserGuideButton.ButtonPushedFcn = createCallbackFcn(app, @UserGuideButtonPushed, true);
            app.UserGuideButton.Text = 'User Guide';
            app.RunAnalysisButton = uibutton(app.HARAMUIFigure, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Icon = 'download.jpg';
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.OFANIDEALAFTERBURNINGTURBOJETLabel = uilabel(app.HARAMUIFigure);
            app.OFANIDEALAFTERBURNINGTURBOJETLabel.Text = 'OF AN IDEAL AFTERBURNING TURBOJET';
            % Show the figure after all components are created
            app.HARAMUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HARAM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HARAMUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HARAMUIFigure)
        end
    end
end